classdef crop_stretching < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        UIAxes             matlab.ui.control.UIAxes
        xstartSliderLabel  matlab.ui.control.Label
        xstartSlider       matlab.ui.control.Slider
        xendSliderLabel    matlab.ui.control.Label
        xendSlider         matlab.ui.control.Slider
        DoneButton         matlab.ui.control.Button
    end

    
    properties (Access = private)
        x_width % Width of x limits
    end
    
    properties (Access = public)
        complete = false % Gets set to true by done button, then data get retrieved
        redline1;
        redline2;
    end
    
    methods (Access = private)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, timevec, height)
            region = timevec>2 & timevec<max(timevec)-1;
            timevec = timevec(region); height = height(region);
            plot(app.UIAxes, timevec, height);
            hold(app.UIAxes, 'on');
            app.UIAxes.XLim = [min(timevec) max(timevec)]; app.UIAxes.YLim = [min(height) max(height)];
            app.redline1 = line(app.UIAxes, 0.3*[app.UIAxes.XLim(2) app.UIAxes.XLim(2)], app.UIAxes.YLim, 'Color', 'r');
            app.redline2 = line(app.UIAxes, 0.6*[app.UIAxes.XLim(2) app.UIAxes.XLim(2)], app.UIAxes.YLim, 'Color', 'r');
            app.x_width = app.UIAxes.XLim(2)-app.UIAxes.XLim(1);
        end

        % Value changing function: xstartSlider
        function xstartSliderValueChanging(app, event)
            changingValue = event.Value;
            app.redline1.XData = app.UIAxes.XLim(1) + changingValue*app.x_width*[1 1];
        end

        % Value changing function: xendSlider
        function xendSliderValueChanging(app, event)
            changingValue = event.Value;
            app.redline2.XData = app.UIAxes.XLim(1) + changingValue*app.x_width*[1 1];
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            app.complete = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Select points from where Stretching is fitted - smaller x -> Stretch onset')
            xlabel(app.UIAxes, 't [s]')
            ylabel(app.UIAxes, '\Delta radius [px]')
            app.xstartSliderLabel = uilabel(app.UIFigure);
            app.xstartSliderLabel.Text = 'x (start)';
            app.xstartSlider = uislider(app.UIFigure);
            app.xstartSlider.Limits = [0 1];
            app.xstartSlider.ValueChangingFcn = createCallbackFcn(app, @xstartSliderValueChanging, true);
            app.xstartSlider.Value = 0.3;
            app.xendSliderLabel = uilabel(app.UIFigure);
            app.xendSliderLabel.Text = 'x (end)';
            app.xendSlider = uislider(app.UIFigure);
            app.xendSlider.Limits = [0 1];
            app.xendSlider.ValueChangingFcn = createCallbackFcn(app, @xendSliderValueChanging, true);
            app.xendSlider.Value = 0.6;
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = crop_stretching(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end